classdef coastlinePredictionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        PredictedRSLcmEditField       matlab.ui.control.EditField
        PredictedRSLcmEditFieldLabel  matlab.ui.control.Label
        CoastlinePredictionVisualizationAppLabel  matlab.ui.control.Label
        StartpredictionvisualizationButton  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        YearDropDown                  matlab.ui.control.DropDown
        YearDropDownLabel             matlab.ui.control.Label
        LocationDropDown              matlab.ui.control.DropDown
        LocationDropDownLabel         matlab.ui.control.Label
        ScenarioDropDown              matlab.ui.control.DropDown
        ScenarioDropDownLabel         matlab.ui.control.Label
        VisualizeContourCheckBox      matlab.ui.control.CheckBox
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        %latCellRange % The min/max cell top-left corner latitude range
        %longCellRange % The min/max cell top-left corner longitude range
        fltDataFiles % An array of file paths for each cell
        region % a geographical region of 1/3 arc-second USGS data
               % Abstractly represents a matrix of 1x1 degree lat/long "cells".
               % Various subranges of this elevation data can then be queried at
               % varying resolutions using the readElevation function.
       geoaxesHandle
    end
    
    methods
        
        function [elevData] = readelevation(app, latRange, longRange, fltDataFiles, varargin)
        %READELEVATION Reads ElevationData from fltDataFiles
        %   elevData = READELEVATION() reads the entire data range
        %
        %   elevData = READELEVATION(latRange, longRange) reads all values
        %   within the region bounded by latRange and longRange
        %
        %   elevData = READELEVATION(...,'PropertyName',PropertyValue)
        %   specifies various additional properties. The sampleFactor
        %   property specifies how the elevation, latitude, and longitude
        %   matrices/vectors should be sampled when trying to reduce the
        %   data size; i.e., take every nth datapoint (default 1). The
        %   display property is a boolean which specifies whether the debug
        %   print statements should be outputted (default false).
            p = inputParser;
            p.addRequired('latRange', @(x) isnumeric(x) && isvector(x) && numel(x) == 2);
            p.addRequired('longRange', @(x) isnumeric(x) && isvector(x) && numel(x) == 2);
            p.addRequired('fltDataFiles');
            p.addParameter('sampleFactor', 1, @(x) floor(x) == x && x > 0);
            p.addParameter('display', false, @(x) isa(x, 'logical'));
            
            % Parse input arguments
            p.parse(latRange, longRange, fltDataFiles, varargin{:});
            inputs = p.Results;
  
            % 1 arc-second resolution = 3612 arc-secs per degree (in data file)
            RASTER_SIZE = 3612;
            
            %Finds the top left corner lats and longs of 1x1 degree cells 
            %required to cover the given ranges
            latRange = [floor(min(latRange)), ceil(max(latRange))];
            longRange = [floor(min(longRange)), ceil(max(longRange))];
            cellLats = min(latRange):max(latRange);
            cellLats = cellLats(2:end);
            cellLongs = min(longRange):max(longRange);
            cellLongs = cellLongs(1:end-1);
            latCellRange = latRange - [1,0];
            longCellRange = longRange + [0,1];
            % The appropriate rows and cols to fetch from fltDataFiles
            
            dataRows = fliplr(latCellRange(end) - cellLats + 1);
            dataCols = abs(longCellRange(1)) - abs(cellLongs) + 1;

            sampleSize = ceil(RASTER_SIZE / inputs.sampleFactor);
            
            elev = zeros(sampleSize * length(cellLats), ...
                         sampleSize * length(cellLongs));
            lats = zeros(sampleSize * length(cellLats), 1);
            longs = zeros(sampleSize * length(cellLongs), 1);
            
            for i = dataRows
                for j = dataCols
                    if inputs.display
                        fprintf('Reading elevation data %d,%d\n', i, j);
                    end
                    
                    % Read the data file
                    try
                        [e, R] = arcgridread(char(inputs.fltDataFiles(i, j)));
                        %[e, R] = readgeoraster(char(inputs.fltDataFiles(i, j)));
                    catch
                        error('Unable to read elevation data. Make sure that the mapping toolbox is installed and specified lat/long range lies inside the region.');
                    end

                    e = e(1:RASTER_SIZE, 1:RASTER_SIZE);
                    
                    % Sample the elevation data
                    e = e(1:inputs.sampleFactor:size(e,1), ...
                          1:inputs.sampleFactor:size(e,2));
                    
                    % Sample the latitude / longitude data; two raster 
                    % referencing information formats are possible
                    if isa(R, 'map.rasterref.GeographicCellsReference')
                        cellLats = linspace(R.LatitudeLimits(1), ...
                                        R.LatitudeLimits(2), RASTER_SIZE);
                        cellLongs = linspace(R.LongitudeLimits(1), ...
                                         R.LongitudeLimits(2), RASTER_SIZE);
                    elseif ismatrix(R)
                        cellLats = linspace(round(R(3, 2)) - 1, ...
                                        round(R(3, 2)), RASTER_SIZE);
                        cellLongs = linspace(round(R(3, 1)), ...
                                        round(R(3, 1)) + 1, RASTER_SIZE);
                    else
                        error('Unrecognized raster referencing information.');
                    end

                    % If on the first row of data, copy the cell longitude
                    % values into the overall longitude vector
                    if i == min(dataRows)
                        longs(sampleSize*(j-1) + 1 : sampleSize*j) ...
                            = cellLongs(1:inputs.sampleFactor:size(cellLongs,2));
                    end
                    
                    % If on the first column of data, copy the cell 
                    % latitude values into the overall latitude vector
                    if j == min(dataCols)
                        lats(sampleSize*(i-1) + 1 : sampleSize*i) ...
                            = fliplr(cellLats(1:inputs.sampleFactor:size(cellLats,2)));
                    end
                    
                    % Copy the cell elevation matrix into the overall
                    % elevation matrix
                    elev(sampleSize * (i - 1) + 1 : sampleSize * i, ...
                         sampleSize * (j - 1) + 1 : sampleSize * j) = e;

                end
            end
          
                   
            if inputs.display
                fprintf('Trimming data to specified range\n');
            end
            
            % Trim off latitudes, longitudes, and corresponding elevations
            % that lie outside the specified ranges
            latInds = lats > min(inputs.latRange) & ...
                           lats < max(inputs.latRange);
            longInds = longs > min(inputs.longRange) & ...
                            longs < max(inputs.longRange);
            
            %elevData = ElevationData;
            elevData.lats = lats(latInds);
            elevData.longs = longs(longInds);
            elevData.elev = elev(latInds, longInds);
            elevData.R = R;    
            %[xs, ys] = grn2eqa(lats(:,ones(length(longs),1)), ...
            %                   longFlip(ones(length(lats),1),:), ...
            %                   [min(lats), min(longs)], ...
            %                   referenceEllipsoid('earth'));


        end
    end

     properties (Access = private)
        %location  % array of locations
        %scenarios % array of scenarios
        data;
    end
%end

    methods (Access = private)
        
        function getData(app)
            T = readtable('data/SLR_TF U.S. Sea Level Projections.csv');
            %remove metadata
            app.data = T(19:end,:);
            %take location colum with unique values only
            locations = table2array(unique(app.data(:,1)));
            scenarios = table2array(unique(app.data(:,10)));
            app.LocationDropDown.Items = locations;
            app.ScenarioDropDown.Items = scenarios;
            %years = 2020:10:2150;
            years = string(2020:10:2150);
            cell_array = cellstr(years);
            %cellArray = num2cell(numArray');
            app.YearDropDown.Items = cell_array;

        end
        
        function prediction = getRSLValue(app)
            %find the prediction row relative to the selected location and
            %scenario
            idx1 = find(strcmp(app.data.MeanSeaLevelDatasetFor_GlobalAndRegionalSeaLevelRiseScenariosFo,app.LocationDropDown.Value));
            idx2 = find(strcmp(app.data.Var10,app.ScenarioDropDown.Value));
            idx = intersect(idx1,idx2);
            %prediction.RSLRow = table2array(app.data(idx,14:end));
            RSLRow = table2array(app.data(idx,15:end));
            prediction.coordinates = table2array(app.data(idx,6:7));
            prediction.RSLValue = RSLRow((str2double(app.YearDropDown.Value) - 2020)/10 + 1);
            %predictionYears = 2020:10:2150;
            %prediction.yearRow = [2005, predictionYears];
            %plot RSL prediction values just as an example            
            %plot(app.UIAxes, prediction.RSLRow, prediction.yearRow);
        end

        function [ region ] = fetchregion(app, latRange, longRange, varargin )
    %FETCHREGION Retrieves a region of USGS elevation data
    %   Downloads, extracts, and indexes all 1x1 degree latitude/longitude
    %   cells necessary to cover the specified latitude and longitude range
    %   from the USGS database (1/3 arc-second, GridFloat format).
    %
    %   region = FETCHREGION(latRange, longRange) downloads and extracts all
    %   cells necessary to cover latRange/longRange and returns a Region appect
    %   containing relevant data file paths
    %
    %   region = FETCHREGION(...,'PropertyName',PropertyValue) specifies
    %   various additional properties. The dataDir property indicates the name
    %   of the directory in which to store the USGS cell data (default
    %   'usgsdata'. The display property is a boolean which specifies whether
    %   the debug print statements should be outputted (default false).
    p = inputParser;
    p.addRequired('latRange', @(x) length(x) == 2);
    p.addRequired('longRange', @(x) length(x) == 2);
    p.addParameter('dataDir', 'usgsdata', @isstr);
    p.addParameter('display', false, @(x) isa(x, 'logical'));
    p.parse(latRange, longRange, varargin{:});
    inputs = p.Results;
    
    % URLs for accessing USGS 1 arc-second GridFloat data, root directory here:
    % https://prd-tnm.s3.amazonaws.com/index.html?prefix=StagedProducts/Elevation/1/GridFloat/
    %
    % There are two URL formats for accessing USGS 1 arc-second GridFloat data, probably due 
    % to different data sets being published at different times.
    %
    % 1/3 arc-second data is available in TIFF format here:
    % https://prd-tnm.s3.amazonaws.com/index.html?prefix=StagedProducts/Elevation/13/TIFF/current/
    % Unfortunately, geotiffread bombs out with a multi-layer error. Presumably readgeoraster
    % fixes this, but it's only available from 2020a which I don't have access to.
    
    URL_FORMAT1 = 'https://prd-tnm.s3.amazonaws.com/StagedProducts/Elevation/1/GridFloat/USGS_NED_1_n%dw%03d_GridFloat.zip';
    URL_FORMAT2 = 'https://prd-tnm.s3.amazonaws.com/StagedProducts/Elevation/1/GridFloat/n%dw%03d.zip';
    DATAFILE_FORMAT1 = '/usgs_ned_1_n%dw%03d_gridfloat.flt';
    DATAFILE_FORMAT2 = '/floatn%dw%03d_1.flt';
    
    %[lats, longs] = rangeparse(inputs.latRange, inputs.longRange);
    
    %Finds the top left corner lats and longs of 1x1 degree cells 
    %required to cover the given ranges
    latRange = [floor(min(latRange)), ceil(max(latRange))];
    longRange = [floor(min(longRange)), ceil(max(longRange))];
    lats = min(latRange):max(latRange);
    lats = lats(2:end);
    longs = min(longRange):max(longRange);
    longs = longs(1:end-1);

    %
    %region = Region;
    region.latCellRange = [lats(1) lats(end)];
    region.longCellRange = [longs(1) longs(end)];
    region.fltDataFiles = cell(length(lats), length(longs));
    region.dataAv = 1;
    if ~exist(inputs.dataDir, 'dir')
        status = mkdir(inputs.dataDir);
        if status == 0
            error('Could not create usgsdata directory.');
        end
    end
    
    for lat = lats
        for long = longs
            if inputs.display
                fprintf('-----Area n%dw%03d-----\n', lat, abs(long));
            end
            zipFile = strcat(inputs.dataDir, sprintf('/n%dw%03d.zip', lat, abs(long)));
            unzipDir = strcat(inputs.dataDir, sprintf('/n%dw%03d', lat, abs(long)));
            
            if ~exist(unzipDir, 'dir')
                if inputs.display
                    fprintf('Downloading area\n');
                end
                
                % Download the zipped files from the USGS server, trying
                % both possible formats
                try
                    websave(zipFile, sprintf(URL_FORMAT1, lat, abs(long)));
                catch
                    try
                        websave(zipFile, sprintf(URL_FORMAT2, lat, abs(long)));
                    catch
                        delete(zipFile);
                        warning('No data available for area n%dw%03d.', ...
                                      lat, abs(long));
                        region.dataAv = 0;
                        return;
                    end
                end
                
                % Extract the zip file
                try
                    if inputs.display
                        fprintf('Extracting area\n');
                    end
                    unzip(zipFile, unzipDir);
                catch
                    error('Unable to unzip. Probably corrupted data.');
                end
                delete(zipFile);
            else
               if inputs.display
                    fprintf('Area already downloaded\n');
                end 
            end
            
            % Copy the path to the raw data file into the appropriate
            % location in the region array
            dataFile1 = strcat(unzipDir, sprintf(DATAFILE_FORMAT1, lat, abs(long)));
            dataFile2 = strcat(unzipDir, sprintf(DATAFILE_FORMAT2, lat, abs(long)));
            if exist(dataFile1, 'file')
                region.fltDataFiles(lats(end) - lat + 1, abs(longs(1)) - ...
                                    abs(long) + 1) = {dataFile1};
            elseif exist(dataFile2, 'file')
                region.fltDataFiles(lats(end) - lat + 1, abs(longs(1)) - ...
                                    abs(long) + 1) = {dataFile2};
            else
                error('Could not locate gridfloat data in USGS download.');
            end
        end
    end
end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            getData(app);
           
        end

        % Button pushed function: StartpredictionvisualizationButton
        function StartpredictionvisualizationButtonPushed(app, event)
             if ~isempty(app.geoaxesHandle) 
                delete(app.geoaxesHandle); 
                pause(0.5);
             end
             %colorbar('off');
             cla(app.UIAxes);
             cla(app.UIAxes2);
             colorbar(app.UIAxes,'off');
             colorbar(app.UIAxes2,'off');
             app.Label.Text = '';
             app.PredictedRSLcmEditField.Value = '';
             pause(0.5);
            %newAxes = axes('Parent', app.UIFigure, 'Position', app.UIFigure.Position);
                cla(app.UIAxes);
                %app.UIFigure = newAxes;
            prediction = getRSLValue(app);
            size = 1;
            latRange = [prediction.coordinates(1) - size/2, prediction.coordinates(1) + size/2];
            longRange = [prediction.coordinates(2) - size/2, prediction.coordinates(2) + size/2];
            region = fetchregion(app, latRange, longRange);
            %region.latCellRange = region.latCellRange - [1, 0];
            %region.longCellRange = region.longCellRange + [0, 1];
            app.PredictedRSLcmEditField.Value = num2str(prediction.RSLValue);
           
            if region.dataAv == 1 
                [elevData] = readelevation(app, latRange, longRange, region.fltDataFiles, 'display', true);
                figPosition = tightPosition(app.UIAxes);
                figUnits = app.UIAxes.Units;
                %ax = worldmap(elevData.elev, elevData.R);
                %app.UIAxes.Visible = 'off';
                %copyobj(ax.Children,app.UIAxes,'legacy');
                %set(app.UIAxes, ax);
                %set(app.UIAxes, get(ax));
                %xlim(app.UIAxes, ax.XLim);
                %ylim(app.UIAxes, ax.YLim);
                %clim(app.UIAxes, ax.CLim)
                %delete(ax.Parent);
                %app.UIAxes.Position = figPosition;
                %set(app.UIAxes, 'Units', ax.Units);
                %set_uiaxes_properties(app.UIAxes, ax.UIAxes);                
                geoshow(app.UIAxes, elevData.elev, elevData.R,'DisplayType','surface');
                colorbar(app.UIAxes);
                %xlabel(app.UIAxes, 'Longitude');
                %ylabel(app.UIAxes, 'Latitude');
                %title(app.UIAxes, 'Elevation Map');
                %TODO fix colormap to show blue from zero to negative
                predictedElev = elevData.elev;
                predictedElev = predictedElev - prediction.RSLValue/100;
                geoshow(app.UIAxes2, predictedElev, elevData.R,'DisplayType','surface');
                colorbar(app.UIAxes2);
                title(app.UIAxes2, 'Predicted Elevation Map');
                xlabel(app.UIAxes2, 'Longitude');
                ylabel(app.UIAxes2, 'Latitude');
            else 
                app.Label.Text = 'Elevation data not available for this area';
                figPosition = tightPosition(app.UIAxes);
                figUnits = app.UIAxes.Units;
                gx = geoaxes('Parent', app.UIFigure);
                gx.Units = figUnits;
                gx.Position = figPosition;
                geolimits(gx, latRange, longRange)
                geobasemap(gx,'bluegreen');
                app.geoaxesHandle = gx;
                
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Current Elevation Map')
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Predicted Elevation Map')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.VisualizeContourCheckBox = uicheckbox(app.Panel);
            app.VisualizeContourCheckBox.Text = 'Visualize Contour ';
            app.ScenarioDropDownLabel = uilabel(app.Panel);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.Panel);
            app.ScenarioDropDown.Items = {};
            app.ScenarioDropDown.Value = {};
            app.LocationDropDownLabel = uilabel(app.Panel);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.Panel);
            app.LocationDropDown.Items = {};
            app.LocationDropDown.Value = {};
            app.YearDropDownLabel = uilabel(app.Panel);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.Panel);
            app.YearDropDown.Items = {};
            app.YearDropDown.Value = {};
            app.StartpredictionvisualizationButton = uibutton(app.UIFigure, 'push');
            app.StartpredictionvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartpredictionvisualizationButtonPushed, true);
            app.StartpredictionvisualizationButton.Text = 'Start prediction visualization';
            app.CoastlinePredictionVisualizationAppLabel = uilabel(app.UIFigure);
            app.CoastlinePredictionVisualizationAppLabel.Text = 'Coastline Prediction Visualization App';
            app.PredictedRSLcmEditFieldLabel = uilabel(app.UIFigure);
            app.PredictedRSLcmEditFieldLabel.Text = 'Predicted RSL (cm)';
            app.PredictedRSLcmEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coastlinePredictionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end